classdef CROWapp02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CROWUIFigure             matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        DIsplayPanel             matlab.ui.container.Panel
        XStartEditFieldLabel     matlab.ui.control.Label
        XStartEditField          matlab.ui.control.NumericEditField
        XendEditFieldLabel       matlab.ui.control.Label
        XendEditField            matlab.ui.control.NumericEditField
        FilenameEditFieldLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        LoadFileButton           matlab.ui.control.Button
        FileformatDropDownLabel  matlab.ui.control.Label
        FileformatDropDown       matlab.ui.control.DropDown
        minLabelStart            matlab.ui.control.Label
        minLabelEnd              matlab.ui.control.Label
        PeaksPanel               matlab.ui.container.Panel
        FindpeaksButton          matlab.ui.control.Button
        CreateBslButton          matlab.ui.control.Button
        ProminceEditFieldLabel   matlab.ui.control.Label
        ProminceEditField        matlab.ui.control.NumericEditField
        BslTypeDropDownLabel     matlab.ui.control.Label
        BslTypeDropDown          matlab.ui.control.DropDown
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditField           matlab.ui.control.NumericEditField
        GlobalbaslineLabel       matlab.ui.control.Label
        LocalbaselineLabel       matlab.ui.control.Label
        CreateLocalBslButton     matlab.ui.control.Button
        PeakTable                matlab.ui.control.Table
        PeaktableLabel           matlab.ui.control.Label
        ExportPanel              matlab.ui.container.Panel
        ExportDataButton         matlab.ui.control.Button
        expformatDropDownLabel   matlab.ui.control.Label
        expformatDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        data % The loaded Chromatogram
        Xstart=0 % Start of the plot of the loaded Chromatogram
        Xend=60 % end of of the plot of the loaded Chromatogram
        NPeaks % Number of peaks
        locs % placering
        w % Bredden af peaks
        idxPeakStart % index fors tart af anlyse område
        BslNormDataInt % Y data hvorfra Bsl er fra trukket
        peaktable=table([NaN(10,1),NaN(10,1),NaN(10,1),NaN(10,1),NaN(10,1),'VariableNames',{'CompNo','RT','Width','Prominence','Area'}]) %Matlab tabel med peak data
        ProminceMin=1 % Brugtes til at afgøre hvirnår det er en Peak
        file
        path
        ConVarBsl=0 %Control Variable der sættes lig 1 når Bsl er lavet
        ConVarBslplot=0 %Control Variable der sættes lig 1 når Bsl er plotet
        BslPlotH
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadDataDropDownValueChanged(app, event)
%            ExBsl=exist("app.BslNormDataInt","var");
%             
%             if ExBsl==1
%                 clf(app.UIAxes)
%             end
%             delete(findobj(app.UIAxes,'type','line'));
%             delete(findobj(app.UIAxes,'type','text'));
%              
%             if app.LoadDataDropDown.Value== "txt"
%                 [app.file,app.path]=uigetfile("*.txt");
%                 figure(app.CROWUIFigure);
%                 
%                 if isequal(app.file,0)
%                     uialert(app.CROWUIFigure,'No file selected','File Error');% Icheck if a file is loaded
%                 else
%                     app.data=readmatrix([app.path,app.file]);
%                     plot(app.UIAxes,app.data(:,1),app.data(:,2),'k');
%                     app.FilenameEditField.Value=app.file;
%                 end
%                 
%             elseif app.LoadDataDropDown.Value== "netCDF"
%                 [app.file,app.path]=uigetfile("*.cdf");
%                 figure(app.CROWUIFigure);
%                 if isequal(app.file,0)
%                     uialert(app.CROWUIFigure,'No file selected','File Error');
%                 else
%                     DATA=mzcdfread([app.path,app.file]);
%                     TIME=[0:DATA.actual_sampling_interval:DATA.actual_run_time_length]';
%                     int=DATA.ordinate_values;
%                     app.data=double([TIME/60,int]);
%                     plot(app.UIAxes,TIME/60,int,'k');
%                     app.FilenameEditField.Value=app.file;
%                 end
%             else
%                 uialert(app.CROWUIFigure,'Fileformat not implented yet','File Error');
%             end
%             
            %app.FilenameEditField.Value=app.file;
        end

        % Value changed function: XStartEditField
        function XStartEditFieldValueChanged(app, event)
            ExVar=exist(app.path);% Tejk for om der er valg data. Hvis ikke =>fejlmelding
            
            if ExVar ~= 7
                uialert(app.CROWUIFigure,'No file selected','File Error');
            else
                
                app.Xstart = app.XStartEditField.Value;
                XLIM=app.UIAxes.XLim;
                
                if app.XStartEditField.Value>= app.XendEditField.Value
                    uialert(app.CROWUIFigure,'X end most be lager than X start','Display Error')
                else
                    app.UIAxes.XLim=[app.Xstart, XLIM(2)];
                end
            end
        end

        % Value changed function: XendEditField
        function XendEditFieldValueChanged(app, event)
            ExVar=exist(app.path);% Tejk for om der er valg data. Hvis ikke =>fejlmelding
            
            if ExVar ~=7
                uialert(app.CROWUIFigure,'No file selected','File Error');
            else
                app.Xend = app.XendEditField.Value;
                XLIM=app.UIAxes.XLim;
                
                if app.XStartEditField.Value>= app.XendEditField.Value
                    uialert(app.CROWUIFigure,'X end most be lager than X start','Display Error')
                else
                    app.UIAxes.XLim=[XLIM(1), app.Xend];
                end
            end
        end

        % Button pushed function: FindpeaksButton
        function FindpeaksButtonPushed(app, event)
            
            ExVar=exist(app.path);% Tejk for om der er valg data. Hvis ikke =>fejlmelding
            
            if ExVar ~=7
                uialert(app.CROWUIFigure,'No file selected','File Error');
            else
                
                DeltaX=app.data(2,1)-app.data(1,1);% Need for defienig the interval for findign the start and end idx
                
                
                % Der skal tjekes om Xend er større eller midnre end chromatogram!
                if app.data(end,1) < app.Xend
                    app.Xend=app.data(end,1)-1;
                end
                
                
                app.idxPeakStart=find(app.data(:,1)>app.Xstart-DeltaX & app.data(:,1)<app.Xstart+DeltaX,1,'first');
                idxPeakEnd=find(app.data(:,1)>app.Xend-DeltaX & app.data(:,1)< app.Xend+DeltaX,1,'last');
                % Need to work on interactivty of the peak find!
                [pks,app.locs,app.w,p]=findpeaks(app.data(app.idxPeakStart:idxPeakEnd,2),'MinPeakProminence',app.ProminceMin); %Peaks are found
                
                RetentionTime=app.data(app.locs-1+app.idxPeakStart,1);
                
                delete(findobj(app.UIAxes,'type','text')); %Fjerne txtx på plot hvis der noget
                text(app.UIAxes,RetentionTime,pks,num2str((1:numel(pks))'));% Peaks are labelt
                
                % Now a table of RT is crated
                app.NPeaks=numel(app.locs);
                CompoundNo=string([1:app.NPeaks])';% Her sættes 1 søjle til string
                PlaceHolderNaN=NaN(app.NPeaks,1);
                app.peaktable=table(CompoundNo,RetentionTime,app.w,p,PlaceHolderNaN);
                app.peaktable.Properties.VariableNames={'CompNo','RT','Width','Prominence','Area'};
                app.PeakTable.Data = app.peaktable;
                app.ConVarBsl=1;
            end
        end

        % Button pushed function: CreateBslButton
        function CreateBslButtonPushed(app, event)
            if app.ConVarBsl==0
                uialert(app.CROWUIFigure,'No peaks found! run "Find peaks','Analysis Error');
            else
             
               if app.ConVarBslplot==1
                  delete(findobj(app.UIAxes,'type','line'));
                  plot(app.UIAxes,app.data(:,1),app.data(:,2),'k');
               end
          
                    Background=app.data;
                    
                    Xs=zeros(app.NPeaks,1);
                    Xe=zeros(app.NPeaks,1);
                    % Idx for stat og slut af peak findes 
                    for i=1:app.NPeaks
                        Xs1=ceil(app.locs(i)-app.WidthEditField.Value*app.w(i))+app.idxPeakStart;
                        Xs(i)=Xs1;
                        Xs2=ceil(app.locs(i)+app.WidthEditField.Value*app.w(i))+app.idxPeakStart;
                        Xe(i)=Xs2;
                    end
                    %Peaks fejernes data, først erstates de medn NaN, For
                    %ikke at ændre idx
                    for k=1:app.NPeaks
                        Background(Xs(k):Xe(k),:)=NaN;
                    end
                    % Her findes alle NaN, så de kan fjernes fra Background
                    nansY=~isnan(Background(:,1));
                    BckX=Background(:,1);
                    BckY=Background(:,2);
                   % Bsl filte til Data uden peak
                    [BackgroundFit,BackgroufGof]=fit(BckX(nansY),BckY(nansY),app.BslTypeDropDown.Value);
                    Bsl1=BackgroundFit(app.data(:,1));
                    
                    hold(app.UIAxes,'on');
                    app.BslPlotH= plot(app.UIAxes,app.data(:,1),Bsl1,'r');
                    app.ConVarBslplot=1;
                    app.BslNormDataInt=app.data(:,2)-BackgroundFit(app.data(:,1));
%                             figure ;
%                             plot(app.data(:,1),app.BslNormDataInt);
%                             xlim([app.Xstart app.Xend]);
%                             xlabel('Time/min');
%                             ylabel('Intensity');
%                             title('Baseline corrected Chroamtogram')
                    
                    % Integration of peaks
                    IntgPeaks=zeros(app.NPeaks,1);
                    for i=1:app.NPeaks
                        IntgP1=trapz(app.data(Xs(i):Xe(i)),app.BslNormDataInt(Xs(i):Xe(i)));
                        IntgPeaks(i)=IntgP1;
                    end
                    %Peak tabel opdaters
                    app.peaktable.Area=IntgPeaks;
                    app.PeakTable.Data = app.peaktable;
                %end
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            ExVar=exist(app.path);% Tejk for om der er valg data. Hvis ikke =>fejlmelding
            
            if ExVar ~=7
                uialert(app.CROWUIFigure,'No file selected','File Error');
            else
                fileUext=split(app.file,'.');
                if app.expformatDropDown.Value=='txt'
                    savefilename= strcat(app.path,cellstr(fileUext(1)),"PeakTable.txt");
                    writetable(app.peaktable,savefilename);
                    
                elseif app.expformatDropDown.Value=='xls'
                    savefilename= strcat(app.path,cellstr(fileUext(1)),"PeakTable.xls");
                    writetable(app.peaktable,savefilename);
                    
                elseif app.expformatDropDown.Value=='mat'
                    savefilename= strcat(app.path,cellstr(fileUext(1)),"PeakTable.mat");
                    table2save=app.peaktable;
                    save(savefilename,'table2save','-mat');
                    %             else
                    %                  uialert(app.CROWUIFigure,'Fileformat not implented yet','File Error');
                end
            end
            
        end

        % Value changed function: ProminceEditField
        function ProminceEditFieldValueChanged(app, event)
            value = app.ProminceEditField.Value;
            app.ProminceMin=value;
        end

        % Button pushed function: CreateLocalBslButton
        function CreateLocalBslButtonPushed(app, event)
            if app.ConVarBsl==0
                uialert(app.CROWUIFigure,'No peaks found! run "Find peaks','Analysis Error');
            else
             
               if app.ConVarBslplot==1
                  delete(findobj(app.UIAxes,'type','line'));
                  plot(app.UIAxes,app.data(:,1),app.data(:,2),'k');
               end
            
            Xs=zeros(app.NPeaks,1);
                    Xe=zeros(app.NPeaks,1);
                    % Idx for stat og slut af peak findes 
                    for i=1:app.NPeaks
                        Xs1=ceil(app.locs(i)-app.WidthEditField.Value*app.w(i))+app.idxPeakStart;
                        Xs(i)=Xs1;
                        Xs2=ceil(app.locs(i)+app.WidthEditField.Value*app.w(i))+app.idxPeakStart;
                        Xe(i)=Xs2;
                    end
            IntgPeaks=zeros(app.NPeaks,1);
            for i=1:app.NPeaks
                LocalBslFitdata=[app.data(Xs(i),1),app.data(Xs(i),2);app.data(Xe(i),1),app.data(Xe(i),2)];
                localBsl1FIT =fit(LocalBslFitdata(:,1),LocalBslFitdata(:,2),'poly1');
                localBsl1=localBsl1FIT(app.data(Xs(i):Xe(i),1));
                 hold(app.UIAxes, 'on');
                %figure
                plot(app.UIAxes, app.data(Xs(i):Xe(i),1),app.data(Xs(i):Xe(i),2),'g');
               
                plot(app.UIAxes,app.data(Xs(i):Xe(i),1),localBsl1,'r');
                LocalBSLcorect=app.data(Xs(i):Xe(i),2)-localBsl1;
%                  plot(app.UIAxes, app.data(Xs(i):Xe(i),1),LocalBSLcorect,'m');
                IntgP1=trapz(app.data(Xs(i):Xe(i),1),LocalBSLcorect);
                IntgPeaks(i)=IntgP1;
                
                app.ConVarBslplot=1;
            end
              %Peak tabel opdaters
                    app.peaktable.Area=IntgPeaks;
                    app.PeakTable.Data = app.peaktable;
            end
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
           delete(findobj(app.UIAxes,'type','line'));
           delete(findobj(app.UIAxes,'type','text'));
           
           if app.FileformatDropDown.Value== "txt"
               [app.file,app.path]=uigetfile("*.txt");
               figure(app.CROWUIFigure);
               
               if isequal(app.file,0)
                   uialert(app.CROWUIFigure,'No file selected','File Error');% Icheck if a file is loaded
               else
                   app.data=readmatrix([app.path,app.file]);
                   plot(app.UIAxes,app.data(:,1),app.data(:,2),'k');
                   app.FilenameEditField.Value=app.file;
               end
               
           elseif app.FileformatDropDown.Value== "netCDF"
               [app.file,app.path]=uigetfile("*.cdf");
               figure(app.CROWUIFigure);
               if isequal(app.file,0)
                   uialert(app.CROWUIFigure,'No file selected','File Error');
               end
                DATA=mzcdfread([app.path,app.file]);
                    TIME=[0:DATA.actual_sampling_interval:DATA.actual_run_time_length]';
                    int=DATA.ordinate_values;
                    app.data=double([TIME/60,int]);
                    plot(app.UIAxes,TIME/60,int,'k');
                    app.FilenameEditField.Value=app.file;
                end
           
        end

        % Cell edit callback: PeakTable
        function PeakTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.peaktable(indices(1),indices(2))={newData}
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CROWUIFigure = uifigure('Visible', 'off');
            app.CROWUIFigure.Name = 'CROW';
            app.UIAxes = uiaxes(app.CROWUIFigure);
            title(app.UIAxes, 'Chromatogram')
            xlabel(app.UIAxes, 'Time/min')
            ylabel(app.UIAxes, '')
            app.DIsplayPanel = uipanel(app.CROWUIFigure);
            app.DIsplayPanel.Title = 'DIsplay';
            app.XStartEditFieldLabel = uilabel(app.DIsplayPanel);
            app.XStartEditFieldLabel.Text = 'X-Start';
            app.XStartEditField = uieditfield(app.DIsplayPanel, 'numeric');
            app.XStartEditField.ValueChangedFcn = createCallbackFcn(app, @XStartEditFieldValueChanged, true);
            app.XStartEditField.Tooltip = {'Ghanges teh Strta time of the shown chromatogram'};
            app.XendEditFieldLabel = uilabel(app.DIsplayPanel);
            app.XendEditFieldLabel.Text = 'X end';
            app.XendEditField = uieditfield(app.DIsplayPanel, 'numeric');
            app.XendEditField.ValueChangedFcn = createCallbackFcn(app, @XendEditFieldValueChanged, true);
            app.XendEditField.Tooltip = {'Changes the end tiim of the shown chromatogram'};
            app.XendEditField.Value = 60;
            app.FilenameEditFieldLabel = uilabel(app.DIsplayPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.DIsplayPanel, 'text');
            app.FilenameEditField.Editable = 'off';
            app.FilenameEditField.Tooltip = {'Shows the current file'};
            app.LoadFileButton = uibutton(app.DIsplayPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FileformatDropDownLabel = uilabel(app.DIsplayPanel);
            app.FileformatDropDownLabel.Text = 'File format';
            app.FileformatDropDown = uidropdown(app.DIsplayPanel);
            app.FileformatDropDown.Items = {'txt', 'netCDF', 'Option 3', 'Option 4'};
            app.FileformatDropDown.Value = 'txt';
            app.minLabelStart = uilabel(app.DIsplayPanel);
            app.minLabelStart.Text = 'min.';
            app.minLabelEnd = uilabel(app.DIsplayPanel);
            app.minLabelEnd.Text = 'min.';
            app.PeaksPanel = uipanel(app.CROWUIFigure);
            app.PeaksPanel.Title = 'Peaks';
            app.FindpeaksButton = uibutton(app.PeaksPanel, 'push');
            app.FindpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindpeaksButtonPushed, true);
            app.FindpeaksButton.Text = 'Find peaks';
            app.CreateBslButton = uibutton(app.PeaksPanel, 'push');
            app.CreateBslButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBslButtonPushed, true);
            app.CreateBslButton.Text = 'Create Bsl';
            app.ProminceEditFieldLabel = uilabel(app.PeaksPanel);
            app.ProminceEditFieldLabel.Text = 'Promince';
            app.ProminceEditField = uieditfield(app.PeaksPanel, 'numeric');
            app.ProminceEditField.Limits = [0 20];
            app.ProminceEditField.ValueDisplayFormat = '%.2f';
            app.ProminceEditField.ValueChangedFcn = createCallbackFcn(app, @ProminceEditFieldValueChanged, true);
            app.ProminceEditField.Tooltip = {'Low promiince gives more peaks'};
            app.ProminceEditField.Value = 1;
            app.BslTypeDropDownLabel = uilabel(app.PeaksPanel);
            app.BslTypeDropDownLabel.Text = 'Bsl Type';
            app.BslTypeDropDown = uidropdown(app.PeaksPanel);
            app.BslTypeDropDown.Items = {'cubicspline', 'poly2', 'Option 3', 'Option 4'};
            app.BslTypeDropDown.Tooltip = {'Chose the type of Global bsl you want '};
            app.BslTypeDropDown.Value = 'cubicspline';
            app.WidthEditFieldLabel = uilabel(app.PeaksPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.PeaksPanel, 'numeric');
            app.WidthEditField.Limits = [0.1 Inf];
            app.WidthEditField.ValueDisplayFormat = '%.1f';
            app.WidthEditField.Tooltip = {'The width of a gausian function fitted to the peak. Is used for estimating where the peak begins and end'; ''};
            app.WidthEditField.Value = 2;
            app.GlobalbaslineLabel = uilabel(app.PeaksPanel);
            app.GlobalbaslineLabel.Text = 'Global basline';
            app.LocalbaselineLabel = uilabel(app.PeaksPanel);
            app.LocalbaselineLabel.Text = 'Local baseline';
            app.CreateLocalBslButton = uibutton(app.PeaksPanel, 'push');
            app.CreateLocalBslButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLocalBslButtonPushed, true);
            app.CreateLocalBslButton.Text = 'Create Local Bsl';
            app.PeakTable = uitable(app.CROWUIFigure);
            app.PeakTable.ColumnName = {'Compound'; 'RT'; 'Gaussian Width'; 'Prominince'; 'Area'};
            app.PeakTable.RowName = {};
            app.PeakTable.ColumnSortable = [true false false false true];
            app.PeakTable.ColumnEditable = [true false false false false];
            app.PeakTable.CellEditCallback = createCallbackFcn(app, @PeakTableCellEdit, true);
            app.PeaktableLabel = uilabel(app.CROWUIFigure);
            app.PeaktableLabel.Text = 'Peaktable';
            app.ExportPanel = uipanel(app.CROWUIFigure);
            app.ExportPanel.Title = 'Export';
            app.ExportDataButton = uibutton(app.ExportPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Tooltip = {'Write the Peaktable in the chose file format'};
            app.ExportDataButton.Text = 'ExportData';
            app.expformatDropDownLabel = uilabel(app.ExportPanel);
            app.expformatDropDownLabel.Text = 'exp format';
            app.expformatDropDown = uidropdown(app.ExportPanel);
            app.expformatDropDown.Items = {'xls', 'mat', 'txt'};
            app.expformatDropDown.Tooltip = {'Chose the format you want export the Peakttabke in'};
            app.expformatDropDown.Value = 'txt';
            % Show the figure after all components are created
            app.CROWUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CROWapp02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CROWUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CROWUIFigure)
        end
    end
end